classdef createSampleFile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SampleConfiguratorUIFigure     matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        CurrentfileLabel               matlab.ui.control.Label
        setgapbetweensamplesLabel      matlab.ui.control.Label
        selectlengthofsinglesampleLabel  matlab.ui.control.Label
        selectstartminuteofsamplecreationLabel  matlab.ui.control.Label
        ConfigurationofsampleparametersLabel  matlab.ui.control.Label
        SamplestarttimeminEditField    matlab.ui.control.NumericEditField
        SamplestarttimeminEditFieldLabel  matlab.ui.control.Label
        SampleshiftminEditField        matlab.ui.control.NumericEditField
        SampleshiftminEditFieldLabel   matlab.ui.control.Label
        MeasurementlengthminEditField  matlab.ui.control.NumericEditField
        MeasurementlengthminEditFieldLabel  matlab.ui.control.Label
        SamplesizeminEditField         matlab.ui.control.NumericEditField
        SamplesizeminEditFieldLabel    matlab.ui.control.Label
        nofileselectedLabel            matlab.ui.control.Label
        SelectfileButton               matlab.ui.control.Button
        OKButton                       matlab.ui.control.Button
    end

    
    properties (Access = private)
        callingapp % mainapp
        filename
        path2file
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
          % Store main app object
          app.callingapp = mainapp;

        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            importdata(app.callingapp, app.SamplestarttimeminEditField.Value, ...
                app.SamplesizeminEditField.Value, ...
                app.MeasurementlengthminEditField.Value, ...
                app.SampleshiftminEditField.Value, ...
                app.filename, app.path2file);
            
            delete(app)
        end

        % Close request function: SampleConfiguratorUIFigure
        function SampleConfiguratorUIFigureCloseRequest(app, event)
            
            % Delete the dialog box 
            delete(app)            
        end

        % Value changed function: SamplesizeminEditField
        function SamplesizeminEditFieldValueChanged(app, event)
            value = app.SamplesizeminEditField.Value;
            
        end

        % Value changed function: MeasurementlengthminEditField
        function MeasurementlengthminEditFieldValueChanged(app, event)
            value = app.MeasurementlengthminEditField.Value;
            
        end

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)
            [file, path] = uigetfile('*.EDF');
            app.filename = file;
            app.path2file = path;
            set(app.nofileselectedLabel, 'Text', file);
            set(app.OKButton, 'Enable', 'on');
        end

        % Value changed function: SampleshiftminEditField
        function SampleshiftminEditFieldValueChanged(app, event)
            value = app.SampleshiftminEditField.Value;
            
        end

        % Value changed function: SamplestarttimeminEditField
        function SamplestarttimeminEditFieldValueChanged(app, event)
            value = app.SamplestarttimeminEditField.Value;
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SampleConfiguratorUIFigure = uifigure('Visible', 'off');
            app.SampleConfiguratorUIFigure.Name = 'Sample Configurator';
            app.SampleConfiguratorUIFigure.Icon = 'HRVEvalToolIcon.png';
            app.SampleConfiguratorUIFigure.CloseRequestFcn = createCallbackFcn(app, @SampleConfiguratorUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.SampleConfiguratorUIFigure);
            app.OKButton = uibutton(app.GridLayout, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Enable = 'off';
            app.OKButton.Text = 'OK';
            app.SelectfileButton = uibutton(app.GridLayout, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Text = 'Select file';
            app.nofileselectedLabel = uilabel(app.GridLayout);
            app.nofileselectedLabel.Text = 'no file selected';
            app.SamplesizeminEditFieldLabel = uilabel(app.GridLayout);
            app.SamplesizeminEditFieldLabel.Text = 'Sample size (min)';
            app.SamplesizeminEditField = uieditfield(app.GridLayout, 'numeric');
            app.SamplesizeminEditField.Limits = [1 Inf];
            app.SamplesizeminEditField.ValueChangedFcn = createCallbackFcn(app, @SamplesizeminEditFieldValueChanged, true);
            app.SamplesizeminEditField.Value = 10;
            app.MeasurementlengthminEditFieldLabel = uilabel(app.GridLayout);
            app.MeasurementlengthminEditFieldLabel.Text = 'Measurement length (min)';
            app.MeasurementlengthminEditField = uieditfield(app.GridLayout, 'numeric');
            app.MeasurementlengthminEditField.Limits = [1 Inf];
            app.MeasurementlengthminEditField.ValueChangedFcn = createCallbackFcn(app, @MeasurementlengthminEditFieldValueChanged, true);
            app.MeasurementlengthminEditField.Value = 1;
            app.SampleshiftminEditFieldLabel = uilabel(app.GridLayout);
            app.SampleshiftminEditFieldLabel.Text = 'Sample shift (min)';
            app.SampleshiftminEditField = uieditfield(app.GridLayout, 'numeric');
            app.SampleshiftminEditField.Limits = [1 Inf];
            app.SampleshiftminEditField.ValueChangedFcn = createCallbackFcn(app, @SampleshiftminEditFieldValueChanged, true);
            app.SampleshiftminEditField.Value = 1;
            app.SamplestarttimeminEditFieldLabel = uilabel(app.GridLayout);
            app.SamplestarttimeminEditFieldLabel.Text = 'Sample start time (min)';
            app.SamplestarttimeminEditField = uieditfield(app.GridLayout, 'numeric');
            app.SamplestarttimeminEditField.ValueChangedFcn = createCallbackFcn(app, @SamplestarttimeminEditFieldValueChanged, true);
            app.ConfigurationofsampleparametersLabel = uilabel(app.GridLayout);
            app.ConfigurationofsampleparametersLabel.Text = 'Configuration of sample parameters';
            app.selectstartminuteofsamplecreationLabel = uilabel(app.GridLayout);
            app.selectstartminuteofsamplecreationLabel.Text = '(select start minute of sample creation)';
            app.selectlengthofsinglesampleLabel = uilabel(app.GridLayout);
            app.selectlengthofsinglesampleLabel.Text = '(select length of single sample)';
            app.setgapbetweensamplesLabel = uilabel(app.GridLayout);
            app.setgapbetweensamplesLabel.Text = '(set gap between samples)';
            app.CurrentfileLabel = uilabel(app.GridLayout);
            app.CurrentfileLabel.Text = 'Current file:';
            % Show the figure after all components are created
            app.SampleConfiguratorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = createSampleFile(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SampleConfiguratorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SampleConfiguratorUIFigure)
        end
    end
end