classdef mainapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        RightPanel                    matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        SelectHRVvariableButtonGroup  matlab.ui.container.ButtonGroup
        DFAalpha1Button               matlab.ui.control.RadioButton
        LFHFPowerFFTButton            matlab.ui.control.RadioButton
        StressindexButton             matlab.ui.control.RadioButton
        RMSSDButton                   matlab.ui.control.RadioButton
        DropDown                      matlab.ui.control.DropDown
        SetstressintervalPanel        matlab.ui.container.Panel
        Switch                        matlab.ui.control.Switch
        LengthminEditField            matlab.ui.control.NumericEditField
        LengthminEditFieldLabel       matlab.ui.control.Label
        StarttimeminEditField         matlab.ui.control.NumericEditField
        StarttimeminEditFieldLabel    matlab.ui.control.Label
        nomeasurementselectedLabel    matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        OpenMenu                      matlab.ui.container.Menu
        CreateexportfileMenu          matlab.ui.container.Menu
        LoadmeasurementMenu           matlab.ui.container.Menu
        PlotMenu                      matlab.ui.container.Menu
        SaveplotMenu                  matlab.ui.container.Menu
        RestoreviewMenu               matlab.ui.container.Menu
        HelpMenu                      matlab.ui.container.Menu
        StepbystepguideMenu           matlab.ui.container.Menu
        TroubleshootingMenu           matlab.ui.container.Menu
        ContactMenu                   matlab.ui.container.Menu
    end

    
    properties (Access = private)
        data % the mat-file that has been loaded
        setup % the setup mat-file
        createSampleFile % the popup to create sample config from
        stressIntervall % the intervall that is highlighted red
    end
    
    methods (Access = public)
        
        function importdata(~, sampleStart, sampleLength, fileLength, sampleShift, filename, path)

             if ~createCSV(sampleStart, sampleLength, fileLength, sampleShift, filename, path)
                msgbox("Something went wrong, please try again!");
             end
             if isunix
                [status, cmdout] = system("cd /; open Applications/" + ...
                    "KubiosHRVPremium/application/KubiosHRVPremium.app");
                if status
                    msgbox("There was an issue starting Kubios HRV Premium! Please try it manually.");
                end
             end
             if ispc
                % TODO: add Windows Support here
             end
        end  
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function setupApp(app)
        
              app.setup = setupHRVvariables();
              
%% TODO: add buttons dynamically, so its easier to add HRV variables
%             vars = app.setup.result.short;
%             buttonGrp = app.SelectHRVvariableButtonGroup;
% 
%             for idx = 1:length(vars)
%                 % TODO: fix the relative pos
%                 ypos = buttonGrp.Position(2) + idx * 15;
%                 rb = uiradiobutton(buttonGrp, 'Position', [5 ypos 123 316]);
%                 rb.Text = vars(idx);
%             end
        end

        % Menu selected function: LoadmeasurementMenu
        function selectFile(app, event)
            % deactivate buttons while loading file
            radioButtons = get(app.SelectHRVvariableButtonGroup, 'Children');
            set(radioButtons, 'Enable', 'off');

            fig = app.UIFigure;
            msg = ['Do you want to load an export with or without' ...
                ' manually configured samples?'];
            title = 'Choose file configuration';
            selection = uiconfirm(fig,msg,title, ...
           'Options',{'With samples','Without samples','Cancel'}, ...
           'DefaultOption',1,'CancelOption',3);
            
            if ~strcmp(selection, 'Cancel')
                [file, path] = uigetfile('*.mat');
                path2file = fullfile(path, file);
                if strcmp(selection, 'Without samples')
                    app.data = WithoutSamplesParser(path2file);
                else
                    app.data = WithSamplesParser(path2file);
                end
            end

            app.selectHRVvariable();

            % enable radio buttons after the file has been load
            set(radioButtons, 'Enable', 'on');
            % show name of selected measurement file
            set(app.nomeasurementselectedLabel, 'Text', file);
        end

        % Selection changed function: SelectHRVvariableButtonGroup
        function selectHRVvariable(app, event)
            selectedButton = app.SelectHRVvariableButtonGroup.SelectedObject;
            selectedVar = selectedButton.Text;
            
            app.data.plotToAxes(app.setup, app.UIAxes, selectedVar);
        end

        % Close request function: UIFigure
        function checkIfSaved(app, event)
            fig = app.UIFigure;
            msg = ['Did you save your evaluation? Otherwise it will' ...
                ' not be saved.'];
            title = 'Evaluation saved?';
            selection = uiconfirm(fig,msg,title, ...
           'Options',{'Yes','No'}, 'DefaultOption',2,'CancelOption',2);
            
            if strcmp(selection, 'Yes')
                delete(app)
            end
        end

        % Menu selected function: CreateexportfileMenu
        function createexportfileButtonPushed(app, event)
            app.createSampleFile = createSampleFile(app);
        end

        % Menu selected function: SaveplotMenu
        function savePlot(app, event)
            filter = {'*.jpg';'*.png';'*.tif';'*.pdf'};
            [filename,filepath] = uiputfile(filter);
            if ischar(filename)
                exportgraphics(app.UIAxes, [filepath filename]);
            end
        end

        % Size changed function: UIFigure
        function updateAppLayout(app, event)

        end

        % Menu selected function: RestoreviewMenu
        function RestoreviewMenuSelected(app, event)
            zoom(app.UIAxes, 'out');
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value, 'On')
                rectX = [app.StarttimeminEditField.Value, app.StarttimeminEditField.Value + app.LengthminEditField.Value];
                rectY = ylim(app.UIAxes);
                app.stressIntervall = patch(app.UIAxes, rectX([1,2,2,1]), rectY([1 1 2 2]), 'red', 'EdgeColor', 'none', 'FaceAlpha', 0.2);
            else
                delete(app.stressIntervall);
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            timeDomainGroup = findobj('Tag','Time-Domain');
            %frequencyDomainGroup = findobj('Tag','Frequency-Domain');
            %nonlinearGroup = findobj('Tag','Nonlinear');

            timeDomainGroup.Enable = 'off';
            timeDomainGroup.Visible = 'off';

            if strcmp("Time-Domain", value)
                timeDomainGroup.Enable = 'on';
                timeDomainGroup.Visible = 'on';
            elseif strcmp("Frequency-Domain", value)

            elseif strcmp("Nonlinear", value)

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @checkIfSaved, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            zlabel(app.UIAxes, 'Z')
            app.nomeasurementselectedLabel = uilabel(app.GridLayout2);
            app.nomeasurementselectedLabel.Text = 'no measurement selected';
            app.SetstressintervalPanel = uipanel(app.GridLayout2);
            app.SetstressintervalPanel.Title = 'Set stress interval';
            app.StarttimeminEditFieldLabel = uilabel(app.SetstressintervalPanel);
            app.StarttimeminEditFieldLabel.Text = 'Start time (min)';
            app.StarttimeminEditField = uieditfield(app.SetstressintervalPanel, 'numeric');
            app.StarttimeminEditField.Limits = [0 Inf];
            app.LengthminEditFieldLabel = uilabel(app.SetstressintervalPanel);
            app.LengthminEditFieldLabel.Text = 'Length (min)';
            app.LengthminEditField = uieditfield(app.SetstressintervalPanel, 'numeric');
            app.LengthminEditField.Limits = [0 Inf];
            app.Switch = uiswitch(app.SetstressintervalPanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.DropDown = uidropdown(app.GridLayout2);
            app.DropDown.Items = {'Time-Domain', 'Frequency-Domain', 'Nonlinear'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Time-Domain';
            app.SelectHRVvariableButtonGroup = uibuttongroup(app.GridLayout2);
            app.SelectHRVvariableButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @selectHRVvariable, true);
            app.SelectHRVvariableButtonGroup.Title = 'Select HRV variable';
            app.SelectHRVvariableButtonGroup.Tag = 'Time-Domain';
            app.RMSSDButton = uiradiobutton(app.SelectHRVvariableButtonGroup);
            app.RMSSDButton.Enable = 'off';
            app.RMSSDButton.Text = 'RMSSD';
            app.RMSSDButton.Value = true;
            app.StressindexButton = uiradiobutton(app.SelectHRVvariableButtonGroup);
            app.StressindexButton.Enable = 'off';
            app.StressindexButton.Text = 'Stress index';
            app.LFHFPowerFFTButton = uiradiobutton(app.SelectHRVvariableButtonGroup);
            app.LFHFPowerFFTButton.Enable = 'off';
            app.LFHFPowerFFTButton.Text = 'LF/HF Power FFT';
            app.DFAalpha1Button = uiradiobutton(app.SelectHRVvariableButtonGroup);
            app.DFAalpha1Button.Enable = 'off';
            app.DFAalpha1Button.Text = 'DFA alpha1';
            app.OpenMenu = uimenu(app.UIFigure);
            app.OpenMenu.Text = 'Open';
            app.CreateexportfileMenu = uimenu(app.OpenMenu);
            app.CreateexportfileMenu.MenuSelectedFcn = createCallbackFcn(app, @createexportfileButtonPushed, true);
            app.CreateexportfileMenu.Separator = 'on';
            app.CreateexportfileMenu.Text = 'Create export file';
            app.LoadmeasurementMenu = uimenu(app.OpenMenu);
            app.LoadmeasurementMenu.MenuSelectedFcn = createCallbackFcn(app, @selectFile, true);
            app.LoadmeasurementMenu.Text = 'Load measurement';
            app.PlotMenu = uimenu(app.UIFigure);
            app.PlotMenu.Text = 'Plot';
            app.SaveplotMenu = uimenu(app.PlotMenu);
            app.SaveplotMenu.MenuSelectedFcn = createCallbackFcn(app, @savePlot, true);
            app.SaveplotMenu.Text = 'Save plot';
            app.RestoreviewMenu = uimenu(app.PlotMenu);
            app.RestoreviewMenu.MenuSelectedFcn = createCallbackFcn(app, @RestoreviewMenuSelected, true);
            app.RestoreviewMenu.Text = 'Restore view';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.StepbystepguideMenu = uimenu(app.HelpMenu);
            app.StepbystepguideMenu.Text = 'Step-by-step guide';
            app.TroubleshootingMenu = uimenu(app.HelpMenu);
            app.TroubleshootingMenu.Text = 'Troubleshooting';
            app.ContactMenu = uimenu(app.HelpMenu);
            app.ContactMenu.Text = 'Contact';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @setupApp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end